classdef LgBeam < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GenerateLGbeamUIFigure          matlab.ui.Figure
        Panel                           matlab.ui.container.Panel
        VariableNameEditFieldLabel      matlab.ui.control.Label
        VariableNameEditField           matlab.ui.control.EditField
        GenerateButton                  matlab.ui.control.Button
        RadialModeSpinnerLabel          matlab.ui.control.Label
        RadialModeSpinner               matlab.ui.control.Spinner
        AzimuthalModeSpinnerLabel       matlab.ui.control.Label
        AzimuthalModeSpinner            matlab.ui.control.Spinner
        WavelengthvacuumEditFieldLabel  matlab.ui.control.Label
        WavelengthvacuumEditField       matlab.ui.control.EditField
        NmaxSpinnerLabel                matlab.ui.control.Label
        NmaxSpinner                     matlab.ui.control.Spinner
        AutoCheckBox                    matlab.ui.control.CheckBox
        IndexmediumEditFieldLabel       matlab.ui.control.Label
        IndexmediumEditField            matlab.ui.control.EditField
        NASpinnerLabel                  matlab.ui.control.Label
        NASpinner                       matlab.ui.control.Spinner
        Gauge                           matlab.ui.control.LinearGauge
        WarningNAlargerthanIndexmediumLabel  matlab.ui.control.Label
        PolarisationEditFieldLabel      matlab.ui.control.Label
        PolarisationEditField           matlab.ui.control.EditField
        PowerEditFieldLabel             matlab.ui.control.Label
        PowerEditField                  matlab.ui.control.EditField
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
    end


    methods (Access = private)
    
        function ProgressCallback(app, progress)
            % Update the guage with the progress value
            app.Gauge.Value = 100*progress;
            drawnow;
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Hide warning about index_medium less than NA
            app.WarningNAlargerthanIndexmediumLabel.Visible = 'off';
            
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            
            % Check variable name for output
            beamName = app.VariableNameEditField.Value;
            if ~isvarname(beamName)
                error('Must supply a valid variable name for output');
            end
            
            % Get parameters from GUI
            radial = app.RadialModeSpinner.Value;
            azimuthal = app.AzimuthalModeSpinner.Value;
            polarisation = evalin('base', app.PolarisationEditField.Value);
            index_medium = evalin('base', app.IndexmediumEditField.Value);
            NA = app.NASpinner.Value;
            wavelength0 = evalin('base', app.WavelengthvacuumEditField.Value);
            power = evalin('base', app.PowerEditField.Value);
            
            % Get Nmax value
            if app.AutoCheckBox.Value
                Nmax = [];
            else
                Nmax = app.NmaxSpinner.Value;
            end
            
            app.ProgressCallback(0);
            
            % Generate beam
            beam = ott.BscPmGauss('lg', [radial, azimuthal], ...
                'wavelength0', wavelength0, ...
                'Nmax', Nmax, ...
                'index_medium', index_medium, ...
                'polarisation', polarisation, ...
                'NA', NA, ...
                'power', power);
               
            % Update the UI with the latest auto Nmax value
            if app.AutoCheckBox.Value
                app.NmaxSpinner.Value = beam.Nmax;
            end
            
            % Write beam to workspace
            assignin('base', beamName, beam);
            
            % Update the displays
            range = [1,1]*ott.utils.nmax2ka(beam.Nmax)/abs(beam.k_medium);
            xrange = linspace(-range(1), range(1), 80)./wavelength0;
            app.ProgressCallback(0.33);
            imZ = beam.visualise('axis', 'z', 'range', range);
            app.ProgressCallback(0.66);
            imY = beam.visualise('axis', 'y', 'range', range);
            imagesc(app.UIAxes, xrange, xrange, imZ);
            axis(app.UIAxes, 'image');
            imagesc(app.UIAxes_2, xrange, xrange, imY);
            axis(app.UIAxes_2, 'image');
            
            app.ProgressCallback(1.0);
            
        end

        % Value changed function: AutoCheckBox
        function AutoCheckBoxValueChanged(app, event)
            
            if app.AutoCheckBox.Value
                app.NmaxSpinner.Enable = 'off';
            else
                app.NmaxSpinner.Enable = 'on';
            end
        end

        % Value changed function: IndexmediumEditField, NASpinner
        function IndexmediumEditFieldValueChanged(app, event)
            
            % Get index medium
            index_medium = evalin('base', app.IndexmediumEditField.Value);
            
            % Toggle warning about NA above index_medium
            if app.NASpinner.Value > index_medium
                app.WarningNAlargerthanIndexmediumLabel.Visible = 'on';
            else
                app.WarningNAlargerthanIndexmediumLabel.Visible = 'off';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GenerateLGbeamUIFigure = uifigure;
            app.GenerateLGbeamUIFigure.Name = 'Generate LG beam';
            app.Panel = uipanel(app.GenerateLGbeamUIFigure);
            app.VariableNameEditFieldLabel = uilabel(app.Panel);
            app.VariableNameEditFieldLabel.Text = 'Variable Name';
            app.VariableNameEditField = uieditfield(app.Panel, 'text');
            app.VariableNameEditField.Value = 'Beam';
            app.GenerateButton = uibutton(app.Panel, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.RadialModeSpinnerLabel = uilabel(app.Panel);
            app.RadialModeSpinnerLabel.Text = 'Radial Mode';
            app.RadialModeSpinner = uispinner(app.Panel);
            app.RadialModeSpinner.Limits = [0 Inf];
            app.RadialModeSpinner.RoundFractionalValues = 'on';
            app.AzimuthalModeSpinnerLabel = uilabel(app.Panel);
            app.AzimuthalModeSpinnerLabel.Text = 'Azimuthal Mode';
            app.AzimuthalModeSpinner = uispinner(app.Panel);
            app.AzimuthalModeSpinner.RoundFractionalValues = 'on';
            app.WavelengthvacuumEditFieldLabel = uilabel(app.Panel);
            app.WavelengthvacuumEditFieldLabel.Text = 'Wavelength (vacuum)';
            app.WavelengthvacuumEditField = uieditfield(app.Panel, 'text');
            app.WavelengthvacuumEditField.Value = '1.0';
            app.NmaxSpinnerLabel = uilabel(app.Panel);
            app.NmaxSpinnerLabel.Text = 'Nmax';
            app.NmaxSpinner = uispinner(app.Panel);
            app.NmaxSpinner.Limits = [1 Inf];
            app.NmaxSpinner.RoundFractionalValues = 'on';
            app.NmaxSpinner.Enable = 'off';
            app.NmaxSpinner.Value = 20;
            app.AutoCheckBox = uicheckbox(app.Panel);
            app.AutoCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoCheckBoxValueChanged, true);
            app.AutoCheckBox.Text = 'Auto';
            app.AutoCheckBox.Value = true;
            app.IndexmediumEditFieldLabel = uilabel(app.Panel);
            app.IndexmediumEditFieldLabel.Text = 'Index (medium)';
            app.IndexmediumEditField = uieditfield(app.Panel, 'text');
            app.IndexmediumEditField.ValueChangedFcn = createCallbackFcn(app, @IndexmediumEditFieldValueChanged, true);
            app.IndexmediumEditField.Value = '1.0';
            app.NASpinnerLabel = uilabel(app.Panel);
            app.NASpinnerLabel.Text = 'NA';
            app.NASpinner = uispinner(app.Panel);
            app.NASpinner.Step = 0.1;
            app.NASpinner.LowerLimitInclusive = 'off';
            app.NASpinner.Limits = [0 Inf];
            app.NASpinner.ValueChangedFcn = createCallbackFcn(app, @IndexmediumEditFieldValueChanged, true);
            app.NASpinner.Value = 0.9;
            app.Gauge = uigauge(app.Panel, 'linear');
            app.Gauge.Enable = 'off';
            app.WarningNAlargerthanIndexmediumLabel = uilabel(app.Panel);
            app.WarningNAlargerthanIndexmediumLabel.Text = 'Warning: NA larger than Index (medium)';
            app.PolarisationEditFieldLabel = uilabel(app.Panel);
            app.PolarisationEditFieldLabel.Text = 'Polarisation';
            app.PolarisationEditField = uieditfield(app.Panel, 'text');
            app.PolarisationEditField.Value = '[1, 1i]';
            app.PowerEditFieldLabel = uilabel(app.Panel);
            app.PowerEditFieldLabel.Text = 'Power';
            app.PowerEditField = uieditfield(app.Panel, 'text');
            app.PowerEditField.Value = '1.0';
            app.UIAxes = uiaxes(app.GenerateLGbeamUIFigure);
            xlabel(app.UIAxes, 'X Position [\lambda_0]')
            ylabel(app.UIAxes, 'Y Position [\lambda_0]')
            app.UIAxes_2 = uiaxes(app.GenerateLGbeamUIFigure);
            xlabel(app.UIAxes_2, 'Z Position [\lambda_0]')
            ylabel(app.UIAxes_2, 'X Position [\lambda_0]')
        end
    end

    methods (Access = public)

        % Construct app
        function app = LgBeam

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GenerateLGbeamUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GenerateLGbeamUIFigure)
        end
    end
end