classdef Visualise < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VisualizebeamUIFigure          matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        FarfieldimageTab               matlab.ui.container.Tab
        DirectionDropDownLabel         matlab.ui.control.Label
        DirectionDropDown              matlab.ui.control.DropDown
        CustomDirectionEditFieldLabel  matlab.ui.control.Label
        CustomDirectionEditField       matlab.ui.control.EditField
        MappingModeDropDownLabel       matlab.ui.control.Label
        MappingModeDropDown            matlab.ui.control.DropDown
        FFYResolutionSpinner           matlab.ui.control.Spinner
        ResolutionSpinner_3Label       matlab.ui.control.Label
        FFXResolutionSpinner           matlab.ui.control.Spinner
        FarfieldsurfaceTab             matlab.ui.container.Tab
        TypeDropDownLabel              matlab.ui.control.Label
        TypeDropDown                   matlab.ui.control.DropDown
        NormaliseCheckBox              matlab.ui.control.CheckBox
        AxesEditFieldLabel             matlab.ui.control.Label
        AxesEditField                  matlab.ui.control.EditField
        ScaleLabel                     matlab.ui.control.Label
        ResolutionSpinnerLabel_2       matlab.ui.control.Label
        FFXResolutionSpinner_2         matlab.ui.control.Spinner
        NearfieldsliceTab              matlab.ui.container.Tab
        OffsetSpinnerLabel             matlab.ui.control.Label
        OffsetSpinner                  matlab.ui.control.Spinner
        WidthSpinnerLabel              matlab.ui.control.Label
        WidthSpinner                   matlab.ui.control.Spinner
        HeightSpinner                  matlab.ui.control.Spinner
        ResolutionSpinnerLabel         matlab.ui.control.Label
        NFXResolutionSpinner           matlab.ui.control.Spinner
        NFYResolutionSpinner           matlab.ui.control.Spinner
        AxisDropDownLabel              matlab.ui.control.Label
        AxisDropDown                   matlab.ui.control.DropDown
        CustomAxesEditFieldLabel       matlab.ui.control.Label
        CustomAxesEditField            matlab.ui.control.EditField
        AutoWidthCheckBox              matlab.ui.control.CheckBox
        Nearfield3DcontourTab          matlab.ui.container.Tab
        LogspacingCheckBox             matlab.ui.control.CheckBox
        ContoursEditFieldLabel         matlab.ui.control.Label
        ContoursEditField              matlab.ui.control.EditField
        Nf3dHeightSpinner              matlab.ui.control.Spinner
        Nf3dYResolutionSpinner         matlab.ui.control.Spinner
        WidthSpinner_2Label            matlab.ui.control.Label
        Nf3dWidthSpinner               matlab.ui.control.Spinner
        ResolutionSpinner_2Label       matlab.ui.control.Label
        Nf3dXResolutionSpinner         matlab.ui.control.Spinner
        Nf3dDepthSpinner               matlab.ui.control.Spinner
        Nf3dZResolutionSpinner         matlab.ui.control.Spinner
        OriginEditFieldLabel           matlab.ui.control.Label
        OriginEditField                matlab.ui.control.EditField
        AutoWidthCheckBox_2            matlab.ui.control.CheckBox
        TextArea                       matlab.ui.control.TextArea
        GenerateButton                 matlab.ui.control.Button
        ReusefigureCheckBox            matlab.ui.control.CheckBox
        AutogenerateCheckBox           matlab.ui.control.CheckBox
        Panel                          matlab.ui.container.Panel
        BasisDropDownLabel             matlab.ui.control.Label
        BasisDropDown                  matlab.ui.control.DropDown
        VisualiseDropDownLabel         matlab.ui.control.Label
        VisualiseDropDown              matlab.ui.control.DropDown
        FieldTypeDropDownLabel         matlab.ui.control.Label
        FieldTypeDropDown              matlab.ui.control.DropDown
        IncidentBeamDropDownLabel      matlab.ui.control.Label
        IncidentBeamDropDown           matlab.ui.control.DropDown
        ScatteredBeamDropDownLabel     matlab.ui.control.Label
        ScatteredBeamDropDown          matlab.ui.control.DropDown
        Gauge                          matlab.ui.control.LinearGauge
    end


    properties (Access = public)
        LastFigure % Handle to the last figure generated
    end

    methods (Access = private)
    
        function VisualiseFarfieldImage(app)
            % Generate the visualisation of the far-field
            
            % Get the beam to visualise
            beam = app.GetBeam();
            
            switch app.DirectionDropDown.Value
                case '+X'
                    direction = [pi/2, 0];
                    labels = struct('x', 'Z', 'y', 'Y');
                case '-X'
                    direction = [-pi/2, 0];
                    labels = struct('x', 'Z', 'y', 'Y');
                case '+Y'
                    direction = [pi/2, pi/2];
                    labels = struct('x', 'Z', 'y', 'X');
                case '-Y'
                    direction = [pi/2, -pi/2];
                    labels = struct('x', 'Z', 'y', 'X');
                case '+Z'
                    direction = 'pos';
                    labels = struct('x', 'X', 'y', 'Y');
                case '-Z'
                    direction = 'neg';
                    labels = struct('x', 'X', 'y', 'Y');
                case 'Custom'
                    direction = evalin('base', app.CustomDirectionEditField.Value);
                    labels = struct('x', '', 'y', '');
                otherwise
                    error('OTT: Internal error: unknown direction drop down value');
            end
            
            beam.visualiseFarfield('direction', direction, ...
                'mapping', app.MappingModeDropDown.Value, ...
                'field', app.FieldTypeDropDown.Value, ...
                'size', [app.FFXResolutionSpinner.Value, app.FFYResolutionSpinner.Value]);
            xlabel(app.LastFigure.CurrentAxes, labels.x);
            ylabel(app.LastFigure.CurrentAxes, labels.y);
            colorbar(app.LastFigure.CurrentAxes);
        end
    
        function VisualiseFarfieldSurface(app)
            % Generate the visualisation of the far-field
            
            % Get the beam to visualise
            beam = app.GetBeam();
            
            % Generate the visualisation
            switch app.TypeDropDown.Value
                case '2dpolar'
%                     beam.visualiseFarfieldSlice();
                case '3dpolar'
                    beam.visualiseFarfieldSphere('npts', app.FFXResolutionSpinner_2.Value, ...
                        'normalise', app.NormaliseCheckBox.Value, ...
                        'type', '3dpolar', ...
                        'field', app.FieldTypeDropDown.Value);
                case 'sphere'
                    beam.visualiseFarfieldSphere('npts', app.FFXResolutionSpinner_2.Value, ...
                        'normalise', app.NormaliseCheckBox.Value, ...
                        'type', 'sphere', ...
                        'field', app.FieldTypeDropDown.Value);
                otherwise
                    error('OTT: Internal error: Unknown type drop down value');
            end
        end
    
        function VisualiseNearfieldImage(app)
            % Generate the visualisation of the near-field
            
            % Get the beam to visualise
            beam = app.GetBeam();
            
            switch app.AxisDropDown.Value
                case 'X-Y'
                    direction = {[1, 0, 0], [0, 1, 0]};
                    labels = struct('x', 'X [\lambda]', 'y', 'Y [\lambda]');
                case 'X-Z'
                    direction = {[0, 0, 1], [1, 0, 0]};
                    labels = struct('x', 'Z [\lambda]', 'y', 'X [\lambda]');
                case 'Y-Z'
                    direction = {[0, 0, 1], [0, 1, 0]};
                    labels = struct('x', 'Z [\lambda]', 'y', 'Y [\lambda]');
                case 'Custom'
                    direction = evalin('base', app.CustomAxesEditField.Value);
                    labels = struct('x', 'direction 1', 'y', 'direction 2');
                otherwise
                    error('OTT: Internal error: unknown axes drop down value');
            end
            
            % Handle width
            if app.AutoWidthCheckBox.Value
                range = [1,1]*ott.utils.nmax2ka(beam.Nmax)/abs(beam.k_medium);
            else
                range = [app.WidthSpinner.Value, app.HeightSpinner.Value];
            end
            
            % Generate the figure
            beam.visualise('axis', direction, ...
                'field', app.FieldTypeDropDown.Value, ...
                'size', [app.NFXResolutionSpinner.Value, app.NFYResolutionSpinner.Value], ...
                'offset', app.OffsetSpinner.Value, ...
                'range', range);
            xlabel(app.LastFigure.CurrentAxes, labels.x);
            ylabel(app.LastFigure.CurrentAxes, labels.y);
            colorbar(app.LastFigure.CurrentAxes);
            
        end
    
        function Visualise3dContours(app)
            % Generate visualisation of 3-D near field with contours
            
            % Get the beam to visualise
            beam = app.GetBeam();
            
            % Translate the beam
            position = evalin('base', app.OriginEditField.Value);
            assert(isnumeric(position) && numel(position) == 3, 'Position must be 3 element vector');
            beam = beam.translateXyz(position(:));
            
            % Handle width
            if app.AutoWidthCheckBox.Value
                range = [1,1,1]*ott.utils.nmax2ka(beam.Nmax)/abs(beam.k_medium);
            else
                range = [app.Nf3dWidthSpinner.Value, app.Nf3dHeightSpinner.Value, app.Nf3dDepthSpinner.Value];
            end
            
            % Get resultion
            resolution = [app.Nf3dXResolutionSpinner.Value, app.Nf3dYResolutionSpinner.Value, app.Nf3dZResolutionSpinner.Value];
            
            x = linspace(-range(1), range(1), resolution(1));
            y = linspace(-range(2), range(2), resolution(2));
            z = linspace(-range(3), range(3), resolution(3));
            
            [xx, yy, zz] = ndgrid(x, y, z);
            xyz = [xx(:), yy(:), zz(:)];
            
            % Calculate the field
            E = beam.emFieldXyz(xyz.');
            
            % Convert from E to desired field
            im = beam.GetVisualisationData(app.FieldTypeDropDown.Value, xyz, [], E.', []);
            
            % Reshape the output
            im = reshape(im, resolution);
            
            % Calculate contour plot levels
            levels = evalin('base', ['[', app.ContoursEditField.Value, ']']);
            if isempty(levels)
                error('Must supply at least one element in levels');
            elseif numel(levels) == 1
                
                minim = min(im(:));
                maxim = max(im(:));
                
                % Equally spaced levels
                if app.LogspacingCheckBox.Value
                    if minim < 0
                        error('Log-scale needs positive valued field');
                    end
                    levels = logspace(log10(minim), log10(maxim), levels(1));
                    disp(levels);
                else
                    levels = linspace(minim, maxim, levels(1));
                end
            elseif numel(levels) == 2 && levels(1) == levels(2)
                levels = levels(1);
            end
            
            cla(app.LastFigure.CurrentAxes);
            disp('Starting contour calculation...');

            cmap = colormap(app.LastFigure.CurrentAxes);

            for ii = 1:length(levels)
                
                % Calculate the color for this contour
                scaled_level = (levels(ii) - min(levels))./(max(levels)-min(levels));
                if isnan(scaled_level)
                    scaled_level = 0.5;
                end
                color = interp1(linspace(0, 1, size(cmap, 1)), cmap, scaled_level);
                
                % Generate the surface
                p = patch(app.LastFigure.CurrentAxes, isosurface(im, levels(ii)), 'EdgeColor', 'none', 'FaceColor', color);
                isonormals(im,p);
                
                hold(app.LastFigure.CurrentAxes, 'on');
            end
            camlight(app.LastFigure.CurrentAxes);
            lighting(app.LastFigure.CurrentAxes, 'phong');
            axis(app.LastFigure.CurrentAxes, 'equal');
            view(app.LastFigure.CurrentAxes, [20, 20]);
            
            disp('done contour calculation');
            
            hold(app.LastFigure.CurrentAxes, 'off');
        end
        
        function beam = GetBeam(app)
            
            % Get incident beam
            if ~isempty(app.IncidentBeamDropDown.Value)
                ibeam = evalin('base', app.IncidentBeamDropDown.Value);
            else
                ibeam = [];
            end
            
            % Get scattered beam
            if ~isempty(app.ScatteredBeamDropDown.Value)
                sbeam = evalin('base', app.ScatteredBeamDropDown.Value);
            else
                sbeam = [];
            end
            
            % Get the scattered, total or incident beam
            switch app.VisualiseDropDown.Value
                case 'incident'
                    if isempty(ibeam)
                        error('Incident beam must be supplied');
                    end
                    beam = ibeam;
                case 'scattered'
                    if isempty(sbeam)
                        error('Scattered beam must be supplied');
                    end
                    if strcmpi(sbeam.type, 'total') && isempty(ibeam)
                        error('Can''t display scattered field, need incident beam');
                    end
                    beam = sbeam.scatteredField(ibeam);
                case 'total'
                    if isempty(sbeam)
                        error('Scattered beam must be supplied');
                    end
                    if strcmpi(sbeam.type, 'scattered') && isempty(ibeam)
                        error('Can''t display total field, need incident beam');
                    end
                    beam = sbeam.totalField(ibeam);
                otherwise
                    error(['OTT: Internal error: invalid visualise drop down value: ' app.VisualiseDropDown.Value]);
            end
            
            % Set the basis
            beam.basis = app.BasisDropDown.Value;
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Look for beams in workspace
            varnames = evalin('base', 'who');
            beams = {};
            for ii = 1:length(varnames)
                if isa(evalin('base', varnames{ii}), 'ott.Bsc')
                    beams{end+1} = varnames{ii};
                end
            end
            
            % Populate beam selector drop downs
            app.IncidentBeamDropDown.Items = beams;
            app.IncidentBeamDropDown.ItemsData = beams;
            app.IncidentBeamDropDown.Value = '';
            app.ScatteredBeamDropDown.Items = beams;
            app.ScatteredBeamDropDown.ItemsData = beams;
            app.ScatteredBeamDropDown.Value = '';
            
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            
            % Open a new figure for the image
            if isempty(app.LastFigure)
                app.LastFigure = figure();
            elseif ~ishandle(app.LastFigure) || ~app.ReusefigureCheckBox.Value
                app.LastFigure = figure();
            else
                figure(app.LastFigure);
            end
            
            % Rest the Gauge
            app.Gauge.Value = 0;
            app.Gauge.BackgroundColor = [1,1,1];
            drawnow;
            
            try
                    
                switch app.TabGroup.SelectedTab.Title
                    case 'Far-field image'
                        app.VisualiseFarfieldImage();
                    case 'Far-field surface'
                        app.VisualiseFarfieldSurface();
                    case 'Near-field slice'
                        app.VisualiseNearfieldImage();
                    case 'Near-field 3-D contour'
                        app.Visualise3dContours();
                    otherwise
                        error('OTT: Internal error: Unknown tab title');
                end
            catch ME
                
                % Show a graphical queue for an error
                app.Gauge.BackgroundColor = [1, 0, 0];
                
                % Continue the error
                rethrow(ME);
            end
            
            % Set the Guage to done
            app.Gauge.Value = 100;
            drawnow;
        end

        % Value changed function: DirectionDropDown
        function DirectionDropDownValueChanged(app, event)
            value = app.DirectionDropDown.Value;
            
            if strcmpi(value, 'Custom')
                app.CustomDirectionEditField.Editable = 'on';
            else
                app.CustomDirectionEditField.Editable = 'off';
                switch app.DirectionDropDown.Value
                    case '+X'
                        direction = [pi/2, 0];
                    case '-X'
                        direction = [-pi/2, 0];
                    case '+Y'
                        direction = [pi/2, pi/2];
                    case '-Y'
                        direction = [pi/2, -pi/2];
                    case '+Z'
                        direction = [0, 0];
                    case '-Z'
                        direction = [pi, 0];
                    case 'Custom'
                        direction = evalin('base', app.CustomDirectionEditField.Value);
                    otherwise
                        error('OTT: Internal error: unknown direction drop down value');
                end
                app.CustomDirectionEditField.Value = ['[', num2str(direction(1)), ', ' num2str(direction(2)) ']'];
            end
            
            % A value changed, so update
            app.AnyValueChanged(event);
                
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            
            switch app.TabGroup.SelectedTab.Title
                case 'Far-field image'
                    if strcmpi(app.BasisDropDown.Value, 'regular')
                        app.BasisDropDown.Value = 'incoming';
                    end
                case 'Far-field surface'
                    if strcmpi(app.BasisDropDown.Value, 'regular')
                        app.BasisDropDown.Value = 'incoming';
                    end
                case 'Near-field slice'
                    if ~strcmpi(app.BasisDropDown.Value, 'regular')
                        app.BasisDropDown.Value = 'regular';
                    end
                case 'Near-field 3-D contour'
                    if ~strcmpi(app.BasisDropDown.Value, 'regular')
                        app.BasisDropDown.Value = 'regular';
                    end
                otherwise
                    error('OTT: Internal error: Unknown tab title');
            end
            
            % A value changed, so update
            app.AnyValueChanged(event);
        end

        % Value changed function: AxesEditField, BasisDropDown, 
        % CustomAxesEditField, CustomDirectionEditField, 
        % FFXResolutionSpinner, FFXResolutionSpinner_2, 
        % FFYResolutionSpinner, FieldTypeDropDown, HeightSpinner, 
        % IncidentBeamDropDown, MappingModeDropDown, 
        % NFXResolutionSpinner, NFYResolutionSpinner, NormaliseCheckBox, 
        % OffsetSpinner, ScatteredBeamDropDown, VisualiseDropDown, 
        % WidthSpinner
        function AnyValueChanged(app, event)
            
            % Generate the image
            if app.AutogenerateCheckBox.Value
                app.GenerateButtonPushed(event);
            end
            
        end

        % Value changed function: AutogenerateCheckBox
        function AutogenerateCheckBoxValueChanged(app, event)
            
            % Auto-generate requires re-use figure to be checked
            if app.AutogenerateCheckBox.Value
                app.ReusefigureCheckBox.Value = true;
                app.ReusefigureCheckBox.Enable = 'off';
            else
                app.ReusefigureCheckBox.Enable = 'on';
            end
            
            % And a value changed, so update
            app.AnyValueChanged(event);
            
        end

        % Value changed function: AxisDropDown
        function AxisDropDownValueChanged(app, event)

            switch app.AxisDropDown.Value
                case 'X-Y'
                    app.CustomAxesEditField.Value = '{[1, 0, 0], [0, 1, 0]}';
                    app.CustomAxesEditField.Editable = 'off';
                case 'X-Z'
                    app.CustomAxesEditField.Value = '{[0, 0, 1], [1, 0, 0]}';
                    app.CustomAxesEditField.Editable = 'off';
                case 'Y-Z'
                    app.CustomAxesEditField.Value = '{[0, 0, 1], [0, 1, 0]}';
                    app.CustomAxesEditField.Editable = 'off';
                case 'Custom'
                    app.CustomAxesEditField.Editable = 'on';
                otherwise
                    error('OTT: Internal error: wrong AxisDropDown value');
            end
            
            % A value changed, so update
            app.AnyValueChanged(event);
            
        end

        % Value changed function: AutoWidthCheckBox
        function AutoWidthCheckBoxValueChanged(app, event)

            % Enable/disable width entry
            if app.AutoWidthCheckBox.Value
                app.WidthSpinner.Enable = 'off';
                app.HeightSpinner.Enable = 'off';
            else
                app.WidthSpinner.Enable = 'on';
                app.HeightSpinner.Enable = 'on';
            end
            
            % A value changed, so update
            app.AnyValueChanged(event);
        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
            
            switch app.TypeDropDown.Value
                case '2dpolar'
                    app.AxesEditField.Enable = 'on';
                case '3dpolar'
                    app.AxesEditField.Enable = 'off';
                case 'sphere'
                    app.AxesEditField.Enable = 'off';
                otherwise
                    error('OTT: Internal error: unknown type drop down value');
            end
            
            % A value changed, so update
            app.AnyValueChanged(event);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VisualizebeamUIFigure = uifigure;
            app.VisualizebeamUIFigure.Name = 'Visualize beam';
            app.TabGroup = uitabgroup(app.VisualizebeamUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.FarfieldimageTab = uitab(app.TabGroup);
            app.FarfieldimageTab.Title = 'Far-field image';
            app.DirectionDropDownLabel = uilabel(app.FarfieldimageTab);
            app.DirectionDropDownLabel.Text = 'Direction';
            app.DirectionDropDown = uidropdown(app.FarfieldimageTab);
            app.DirectionDropDown.Items = {'+X', '-X', '+Y', '-Y', '+Z', '-Z', 'Custom'};
            app.DirectionDropDown.ItemsData = {'+X', '-X', '+Y', '-Y', '+Z', '-Z', 'Custom'};
            app.DirectionDropDown.ValueChangedFcn = createCallbackFcn(app, @DirectionDropDownValueChanged, true);
            app.DirectionDropDown.Value = '+Z';
            app.CustomDirectionEditFieldLabel = uilabel(app.FarfieldimageTab);
            app.CustomDirectionEditFieldLabel.Text = 'Custom Direction';
            app.CustomDirectionEditField = uieditfield(app.FarfieldimageTab, 'text');
            app.CustomDirectionEditField.ValueChangedFcn = createCallbackFcn(app, @AnyValueChanged, true);
            app.CustomDirectionEditField.Editable = 'off';
            app.CustomDirectionEditField.Value = '[0, 0]';
            app.MappingModeDropDownLabel = uilabel(app.FarfieldimageTab);
            app.MappingModeDropDownLabel.Text = 'Mapping Mode';
            app.MappingModeDropDown = uidropdown(app.FarfieldimageTab);
            app.MappingModeDropDown.Items = {'sin(theta)', 'tan(theta)', 'theta'};
            app.MappingModeDropDown.ItemsData = {'sin', 'tan', 'theta'};
            app.MappingModeDropDown.ValueChangedFcn = createCallbackFcn(app, @AnyValueChanged, true);
            app.MappingModeDropDown.Value = 'sin';
            app.FFYResolutionSpinner = uispinner(app.FarfieldimageTab);
            app.FFYResolutionSpinner.LowerLimitInclusive = 'off';
            app.FFYResolutionSpinner.Limits = [0 Inf];
            app.FFYResolutionSpinner.RoundFractionalValues = 'on';
            app.FFYResolutionSpinner.ValueChangedFcn = createCallbackFcn(app, @AnyValueChanged, true);
            app.FFYResolutionSpinner.Value = 80;
            app.ResolutionSpinner_3Label = uilabel(app.FarfieldimageTab);
            app.ResolutionSpinner_3Label.Text = 'Resolution';
            app.FFXResolutionSpinner = uispinner(app.FarfieldimageTab);
            app.FFXResolutionSpinner.LowerLimitInclusive = 'off';
            app.FFXResolutionSpinner.Limits = [0 Inf];
            app.FFXResolutionSpinner.RoundFractionalValues = 'on';
            app.FFXResolutionSpinner.ValueChangedFcn = createCallbackFcn(app, @AnyValueChanged, true);
            app.FFXResolutionSpinner.Value = 80;
            app.FarfieldsurfaceTab = uitab(app.TabGroup);
            app.FarfieldsurfaceTab.Title = 'Far-field surface';
            app.TypeDropDownLabel = uilabel(app.FarfieldsurfaceTab);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.FarfieldsurfaceTab);
            app.TypeDropDown.Items = {'2-D Polar plot', '3-D Polar plot', 'Sphere'};
            app.TypeDropDown.ItemsData = {'2dpolar', '3dpolar', 'sphere'};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.TypeDropDown.Value = '3dpolar';
            app.NormaliseCheckBox = uicheckbox(app.FarfieldsurfaceTab);
            app.NormaliseCheckBox.ValueChangedFcn = createCallbackFcn(app, @AnyValueChanged, true);
            app.NormaliseCheckBox.Text = 'Normalise';
            app.AxesEditFieldLabel = uilabel(app.FarfieldsurfaceTab);
            app.AxesEditFieldLabel.Enable = 'off';
            app.AxesEditFieldLabel.Text = 'Axes';
            app.AxesEditField = uieditfield(app.FarfieldsurfaceTab, 'text');
            app.AxesEditField.ValueChangedFcn = createCallbackFcn(app, @AnyValueChanged, true);
            app.AxesEditField.Enable = 'off';
            app.AxesEditField.Value = '[1, 0, 0; 0, 1, 0]';
            app.ScaleLabel = uilabel(app.FarfieldsurfaceTab);
            app.ScaleLabel.Text = 'Scale';
            app.ResolutionSpinnerLabel_2 = uilabel(app.FarfieldsurfaceTab);
            app.ResolutionSpinnerLabel_2.Text = 'Resolution';
            app.FFXResolutionSpinner_2 = uispinner(app.FarfieldsurfaceTab);
            app.FFXResolutionSpinner_2.LowerLimitInclusive = 'off';
            app.FFXResolutionSpinner_2.Limits = [0 Inf];
            app.FFXResolutionSpinner_2.RoundFractionalValues = 'on';
            app.FFXResolutionSpinner_2.ValueChangedFcn = createCallbackFcn(app, @AnyValueChanged, true);
            app.FFXResolutionSpinner_2.Value = 80;
            app.NearfieldsliceTab = uitab(app.TabGroup);
            app.NearfieldsliceTab.Title = 'Near-field slice';
            app.OffsetSpinnerLabel = uilabel(app.NearfieldsliceTab);
            app.OffsetSpinnerLabel.Text = 'Offset';
            app.OffsetSpinner = uispinner(app.NearfieldsliceTab);
            app.OffsetSpinner.Step = 0.2;
            app.OffsetSpinner.ValueChangedFcn = createCallbackFcn(app, @AnyValueChanged, true);
            app.WidthSpinnerLabel = uilabel(app.NearfieldsliceTab);
            app.WidthSpinnerLabel.Enable = 'off';
            app.WidthSpinnerLabel.Text = 'Width';
            app.WidthSpinner = uispinner(app.NearfieldsliceTab);
            app.WidthSpinner.Step = 0.2;
            app.WidthSpinner.LowerLimitInclusive = 'off';
            app.WidthSpinner.Limits = [0 Inf];
            app.WidthSpinner.ValueChangedFcn = createCallbackFcn(app, @AnyValueChanged, true);
            app.WidthSpinner.Enable = 'off';
            app.WidthSpinner.Value = 1;
            app.HeightSpinner = uispinner(app.NearfieldsliceTab);
            app.HeightSpinner.Step = 0.2;
            app.HeightSpinner.LowerLimitInclusive = 'off';
            app.HeightSpinner.Limits = [0 Inf];
            app.HeightSpinner.ValueChangedFcn = createCallbackFcn(app, @AnyValueChanged, true);
            app.HeightSpinner.Enable = 'off';
            app.HeightSpinner.Value = 1;
            app.ResolutionSpinnerLabel = uilabel(app.NearfieldsliceTab);
            app.ResolutionSpinnerLabel.Text = 'Resolution';
            app.NFXResolutionSpinner = uispinner(app.NearfieldsliceTab);
            app.NFXResolutionSpinner.LowerLimitInclusive = 'off';
            app.NFXResolutionSpinner.Limits = [0 Inf];
            app.NFXResolutionSpinner.RoundFractionalValues = 'on';
            app.NFXResolutionSpinner.ValueChangedFcn = createCallbackFcn(app, @AnyValueChanged, true);
            app.NFXResolutionSpinner.Value = 80;
            app.NFYResolutionSpinner = uispinner(app.NearfieldsliceTab);
            app.NFYResolutionSpinner.LowerLimitInclusive = 'off';
            app.NFYResolutionSpinner.Limits = [0 Inf];
            app.NFYResolutionSpinner.RoundFractionalValues = 'on';
            app.NFYResolutionSpinner.ValueChangedFcn = createCallbackFcn(app, @AnyValueChanged, true);
            app.NFYResolutionSpinner.Value = 80;
            app.AxisDropDownLabel = uilabel(app.NearfieldsliceTab);
            app.AxisDropDownLabel.Text = 'Axis';
            app.AxisDropDown = uidropdown(app.NearfieldsliceTab);
            app.AxisDropDown.Items = {'X-Y', 'X-Z', 'Y-Z', 'Custom'};
            app.AxisDropDown.ValueChangedFcn = createCallbackFcn(app, @AxisDropDownValueChanged, true);
            app.AxisDropDown.Value = 'X-Y';
            app.CustomAxesEditFieldLabel = uilabel(app.NearfieldsliceTab);
            app.CustomAxesEditFieldLabel.Text = 'Custom Axes';
            app.CustomAxesEditField = uieditfield(app.NearfieldsliceTab, 'text');
            app.CustomAxesEditField.ValueChangedFcn = createCallbackFcn(app, @AnyValueChanged, true);
            app.CustomAxesEditField.Editable = 'off';
            app.CustomAxesEditField.Value = '{[1, 0, 0], [0, 1, 0]}';
            app.AutoWidthCheckBox = uicheckbox(app.NearfieldsliceTab);
            app.AutoWidthCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoWidthCheckBoxValueChanged, true);
            app.AutoWidthCheckBox.Text = 'Auto Width';
            app.AutoWidthCheckBox.Value = true;
            app.Nearfield3DcontourTab = uitab(app.TabGroup);
            app.Nearfield3DcontourTab.Title = 'Near-field 3-D contour';
            app.LogspacingCheckBox = uicheckbox(app.Nearfield3DcontourTab);
            app.LogspacingCheckBox.Text = 'Log-spacing';
            app.ContoursEditFieldLabel = uilabel(app.Nearfield3DcontourTab);
            app.ContoursEditFieldLabel.Text = 'Contours';
            app.ContoursEditField = uieditfield(app.Nearfield3DcontourTab, 'text');
            app.ContoursEditField.Value = '1';
            app.Nf3dHeightSpinner = uispinner(app.Nearfield3DcontourTab);
            app.Nf3dHeightSpinner.Step = 0.2;
            app.Nf3dHeightSpinner.LowerLimitInclusive = 'off';
            app.Nf3dHeightSpinner.Limits = [0 Inf];
            app.Nf3dHeightSpinner.Enable = 'off';
            app.Nf3dHeightSpinner.Value = 1;
            app.Nf3dYResolutionSpinner = uispinner(app.Nearfield3DcontourTab);
            app.Nf3dYResolutionSpinner.Limits = [1 Inf];
            app.Nf3dYResolutionSpinner.Value = 40;
            app.WidthSpinner_2Label = uilabel(app.Nearfield3DcontourTab);
            app.WidthSpinner_2Label.Enable = 'off';
            app.WidthSpinner_2Label.Text = 'Width';
            app.Nf3dWidthSpinner = uispinner(app.Nearfield3DcontourTab);
            app.Nf3dWidthSpinner.Step = 0.2;
            app.Nf3dWidthSpinner.LowerLimitInclusive = 'off';
            app.Nf3dWidthSpinner.Limits = [0 Inf];
            app.Nf3dWidthSpinner.Enable = 'off';
            app.Nf3dWidthSpinner.Value = 1;
            app.ResolutionSpinner_2Label = uilabel(app.Nearfield3DcontourTab);
            app.ResolutionSpinner_2Label.Text = 'Resolution';
            app.Nf3dXResolutionSpinner = uispinner(app.Nearfield3DcontourTab);
            app.Nf3dXResolutionSpinner.Limits = [1 Inf];
            app.Nf3dXResolutionSpinner.Value = 40;
            app.Nf3dDepthSpinner = uispinner(app.Nearfield3DcontourTab);
            app.Nf3dDepthSpinner.Step = 0.2;
            app.Nf3dDepthSpinner.LowerLimitInclusive = 'off';
            app.Nf3dDepthSpinner.Limits = [0 Inf];
            app.Nf3dDepthSpinner.Enable = 'off';
            app.Nf3dDepthSpinner.Value = 1;
            app.Nf3dZResolutionSpinner = uispinner(app.Nearfield3DcontourTab);
            app.Nf3dZResolutionSpinner.Limits = [1 Inf];
            app.Nf3dZResolutionSpinner.Value = 40;
            app.OriginEditFieldLabel = uilabel(app.Nearfield3DcontourTab);
            app.OriginEditFieldLabel.Text = 'Origin';
            app.OriginEditField = uieditfield(app.Nearfield3DcontourTab, 'text');
            app.OriginEditField.Value = '[0,0,0]';
            app.AutoWidthCheckBox_2 = uicheckbox(app.Nearfield3DcontourTab);
            app.AutoWidthCheckBox_2.Text = 'Auto Width';
            app.AutoWidthCheckBox_2.Value = true;
            app.TextArea = uitextarea(app.VisualizebeamUIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'This GUI can be used to create a figure for visualizing beams.  Specify variable names for either or both the incident beam and the scattered beam, select the field type, and choose the visualization type.  Click generate to create a new figure.'; ''; 'Far-field visualizations should use either the incoming or outgoing basis, near field should use the regular basis.'; ''; 'Far-field image: Generate a image of the far-field.  Specify the direction to project and the mapping between spherical and Cartesian coordinates.  The custom direction can be a rotation matrix or 2 element vector (theta: rotation from z axis, phi: rotation from +x to +y).'; ''; 'Far-field surface:  Generate a polar plot, 3-D polar plot or shaded sphere plot.  Options to normalize the field.  Slice axes can be specified for the 2-D polar plot as a Nx3 matrix.'; ''; 'Near-field slice:  Generate a 2-D slice (image) of the field.  Specify the width [wavelengths], resolution, and offset along the axis from the origin [wavelengths].'; ''; 'Near-field 3-D contour: Generate a 3-D contour plot of the field.  Specify the width [wavelengths], resolution, origin [wavelengths] and contours.  Contours can be a number or the contour values (see input of contour).'};
            app.GenerateButton = uibutton(app.VisualizebeamUIFigure, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.ReusefigureCheckBox = uicheckbox(app.VisualizebeamUIFigure);
            app.ReusefigureCheckBox.Text = 'Re-use figure';
            app.ReusefigureCheckBox.Value = true;
            app.AutogenerateCheckBox = uicheckbox(app.VisualizebeamUIFigure);
            app.AutogenerateCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutogenerateCheckBoxValueChanged, true);
            app.AutogenerateCheckBox.Text = 'Auto-generate';
            app.Panel = uipanel(app.VisualizebeamUIFigure);
            app.BasisDropDownLabel = uilabel(app.Panel);
            app.BasisDropDownLabel.Text = 'Basis';
            app.BasisDropDown = uidropdown(app.Panel);
            app.BasisDropDown.Items = {'Incoming', 'Outgoing', 'Regular'};
            app.BasisDropDown.ItemsData = {'incoming', 'outgoing', 'regular'};
            app.BasisDropDown.ValueChangedFcn = createCallbackFcn(app, @AnyValueChanged, true);
            app.BasisDropDown.Value = 'incoming';
            app.VisualiseDropDownLabel = uilabel(app.Panel);
            app.VisualiseDropDownLabel.Text = 'Visualise';
            app.VisualiseDropDown = uidropdown(app.Panel);
            app.VisualiseDropDown.Items = {'Incident Beam', 'Scattered Beam', 'Total Beam'};
            app.VisualiseDropDown.ItemsData = {'incident', 'scattered', 'total'};
            app.VisualiseDropDown.ValueChangedFcn = createCallbackFcn(app, @AnyValueChanged, true);
            app.VisualiseDropDown.Value = 'incident';
            app.FieldTypeDropDownLabel = uilabel(app.Panel);
            app.FieldTypeDropDownLabel.Text = 'Field Type';
            app.FieldTypeDropDown = uidropdown(app.Panel);
            app.FieldTypeDropDown.Items = {'E Intensity', 'Re(Er)', 'Re(Etheta)', 'Re(Ephi)', 'Re(Ex)', 'Re(Ey)', 'Re(Ez)', '|Er|', '|Etheta|', '|Ephi|', '|Ex|', '|Ey|', '|Ez|'};
            app.FieldTypeDropDown.ItemsData = {'irradiance', 'Re(Er)', 'Re(Et)', 'Re(Ep)', 'Re(Ex)', 'Re(Ey)', 'Re(Ez)', 'Abs(Er)', 'Abs(Et)', 'Abs(Ep)', 'Abs(Ex)', 'Abs(Ey)', 'Abs(Ez)'};
            app.FieldTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @AnyValueChanged, true);
            app.FieldTypeDropDown.Value = 'irradiance';
            app.IncidentBeamDropDownLabel = uilabel(app.Panel);
            app.IncidentBeamDropDownLabel.Text = 'Incident Beam';
            app.IncidentBeamDropDown = uidropdown(app.Panel);
            app.IncidentBeamDropDown.Items = {};
            app.IncidentBeamDropDown.Editable = 'on';
            app.IncidentBeamDropDown.ValueChangedFcn = createCallbackFcn(app, @AnyValueChanged, true);
            app.IncidentBeamDropDown.Value = {};
            app.ScatteredBeamDropDownLabel = uilabel(app.Panel);
            app.ScatteredBeamDropDownLabel.Text = 'Scattered Beam';
            app.ScatteredBeamDropDown = uidropdown(app.Panel);
            app.ScatteredBeamDropDown.Items = {};
            app.ScatteredBeamDropDown.Editable = 'on';
            app.ScatteredBeamDropDown.ValueChangedFcn = createCallbackFcn(app, @AnyValueChanged, true);
            app.ScatteredBeamDropDown.Value = {};
            app.Gauge = uigauge(app.VisualizebeamUIFigure, 'linear');
            app.Gauge.Enable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Visualise

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VisualizebeamUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VisualizebeamUIFigure)
        end
    end
end