classdef ForceProfile < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CalculateForceTorqueProfilesUIFigure  matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        Panel                          matlab.ui.container.Panel
        CalculateButton                matlab.ui.control.Button
        OutputEditFieldLabel           matlab.ui.control.Label
        OutputEditField                matlab.ui.control.EditField
        DirectionDropDownLabel         matlab.ui.control.Label
        DirectionDropDown              matlab.ui.control.DropDown
        ResolutionSpinnerLabel         matlab.ui.control.Label
        ResolutionSpinner              matlab.ui.control.Spinner
        RangeSpinnerLabel              matlab.ui.control.Label
        RangeSpinner                   matlab.ui.control.Spinner
        RangeSpinner_2                 matlab.ui.control.Spinner
        Gauge                          matlab.ui.control.LinearGauge
        BeamDropDownLabel              matlab.ui.control.Label
        BeamDropDown                   matlab.ui.control.DropDown
        TmatrixDropDownLabel           matlab.ui.control.Label
        TmatrixDropDown                matlab.ui.control.DropDown
        InitialPositionEditFieldLabel  matlab.ui.control.Label
        InitialPositionEditField       matlab.ui.control.EditField
        InitialRotationEditFieldLabel  matlab.ui.control.Label
        InitialRotationEditField       matlab.ui.control.EditField
    end


    properties (Access = public)
        data % Data calculated by GUI
    end

    methods (Access = private)
    
        function ProgressCallback(app, progress)
            app.Gauge.Value = progress*100;
            drawnow;
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.data = [];
            
            % Look for beams in workspace
            varnames = evalin('base', 'who');
            beams = {};
            particles = {};
            for ii = 1:length(varnames)
                if isa(evalin('base', varnames{ii}), 'ott.Bsc')
                    beams{end+1} = varnames{ii};
                end
                if isa(evalin('base', varnames{ii}), 'ott.Tmatrix')
                    particles{end+1} = varnames{ii};
                end
            end
            
            % Populate beam selector drop downs
            app.BeamDropDown.Items = beams;
            app.BeamDropDown.ItemsData = beams;
            app.BeamDropDown.Value = '';
            
            % Populate T-matrix selector drop down
            app.TmatrixDropDown.Items = particles;
            app.TmatrixDropDown.ItemsData = particles;
            app.TmatrixDropDown.Value = '';
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            
            % Get origin position
            x0 = evalin('base', app.InitialPositionEditField.Value);
            assert(isnumeric(x0) && numel(x0) == 3, 'Position must be 3 element numeric vector');
            
            % Get origin rotation
            R0 = evalin('base', app.InitialRotationEditField.Value);
            assert(isnumeric(R0) && (numel(R0) == 2 || all(size(R0) == [3,3])), ...
                'Rotation must be 2 element vector or 3x3 matrix');
            if numel(R0) == 2
                % Generate rotation matrix
                R0 = ott.utils.rotz(R0(2))*ott.utils.roty(R0(1));
            end
            
            % Get resolution and range values
            numpts = app.ResolutionSpinner.Value;
            range = [app.RangeSpinner.Value, app.RangeSpinner_2.Value];
            
            % Get beam
            if isempty(app.BeamDropDown.Value)
                error('Must supply a beam object');
            end
            beam = evalin('base', app.BeamDropDown.Value);
            
            % Get T-matrix
            if isempty(app.TmatrixDropDown.Value)
                error('Must supply a T-matrix object');
            end
            Tmatrix = evalin('base', app.TmatrixDropDown.Value);
            
            % Calculate rotated positions/rotations
            xrange = linspace(range(1), range(2), numpts);
            switch app.DirectionDropDown.Value
                case 'x'
                    x = [1;0;0].*xrange*beam.wavelength + x0(:);
                    R = R0;
                case 'y'
                    x = [0;1;0].*xrange*beam.wavelength + x0(:);
                    R = R0;
                case 'z'
                    x = [0;0;1].*xrange*beam.wavelength + x0(:);
                    R = R0;
                case 'Rx'
                    x = x0(:);
                    R = ott.utils.rotx(xrange*180/pi);
                case 'Ry'
                    x = x0(:);
                    R = ott.utils.roty(xrange*180/pi);
                case 'Rz'
                    x = x0(:);
                    R = ott.utils.rotz(xrange*180/pi);
                otherwise
                    error('OTT: Internal error: Unknown DirectionDropDown value');
            end
            
            app.data = struct();
            
            app.data.x = x;
            app.data.R = R;
            
            % Disable the button and reset Gauge
            app.Gauge.Value = 0;
            app.Gauge.BackgroundColor = [1,1,1];
            app.CalculateButton.Enable = 'off';
            drawnow;
            
            try
            
                % Calculate force/torque profile
                beam.basis = 'regular';
                [force, torque] = ott.forcetorque(beam, Tmatrix, 'position', x, 'rotation', R, ...
                    'progress_callback', @app.ProgressCallback);
                app.data.force = force;
                app.data.torque = torque;
                
            catch ME
                
                % Tidy up UI
                app.Gauge.BackgroundColor = [1,0,0];
                app.CalculateButton.Enable = 'on';
                
                rethrow(ME);
            end
            
            % Tidy up UI
            app.Gauge.Value = 100;
            app.CalculateButton.Enable = 'on';
            drawnow;
            
            % Update graphs
            plot(app.UIAxes, xrange, app.data.force);
            plot(app.UIAxes2, xrange, app.data.torque);
            legend(app.UIAxes, {'X', 'Y', 'Z'});
            legend(app.UIAxes2, {'X', 'Y', 'Z'});
            if any(strcmpi(app.DirectionDropDown.Value, {'x', 'y', 'z'}))
                xlabel(app.UIAxes, 'Position [\lambda]');
                xlabel(app.UIAxes2, 'Position [\lambda]');
            else
                xlabel(app.UIAxes, 'Angle [rad]');
                xlabel(app.UIAxes2, 'Angle [rad]');
            end
            
            % Store output
            if isvarname(app.OutputEditField.Value)
                assignin('base', app.OutputEditField.Value, app.data);
            end
        end

        % Value changed function: OutputEditField
        function OutputEditFieldValueChanged(app, event)
            
            % Store output
            if ~isempty(app.data) && isvarname(app.OutputEditField.Value)
                assignin('base', app.OutputEditField.Value, app.data);
            end
        end

        % Callback function
        function DirectionDropDownValueChanged(app, event)
            
            if any(strcmpi(app.DirectionDropDown.Value, {'x', 'y', 'z'}))
                app.RangeSpinner.Value = -2;
                app.RangeSpinner_2.Value = 2;
            else
                app.RangeSpinner.Value = -pi;
                app.RangeSpinner_2.Value = pi;
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CalculateForceTorqueProfilesUIFigure = uifigure;
            app.CalculateForceTorqueProfilesUIFigure.Name = 'Calculate Force/Torque Profiles';
            app.UIAxes = uiaxes(app.CalculateForceTorqueProfilesUIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Force')
            app.UIAxes2 = uiaxes(app.CalculateForceTorqueProfilesUIFigure);
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Torque')
            app.Panel = uipanel(app.CalculateForceTorqueProfilesUIFigure);
            app.CalculateButton = uibutton(app.Panel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.OutputEditFieldLabel = uilabel(app.Panel);
            app.OutputEditFieldLabel.Text = 'Output';
            app.OutputEditField = uieditfield(app.Panel, 'text');
            app.OutputEditField.ValueChangedFcn = createCallbackFcn(app, @OutputEditFieldValueChanged, true);
            app.OutputEditField.Value = 'ForceTorqueData';
            app.DirectionDropDownLabel = uilabel(app.Panel);
            app.DirectionDropDownLabel.Text = 'Direction';
            app.DirectionDropDown = uidropdown(app.Panel);
            app.DirectionDropDown.Items = {'X Translation', 'Y Translation', 'Z Translation', 'X Rotation', 'Y Rotation', 'Z Rotation'};
            app.DirectionDropDown.ItemsData = {'x', 'y', 'z', 'Rx', 'Ry', 'Rz'};
            app.DirectionDropDown.Value = 'z';
            app.ResolutionSpinnerLabel = uilabel(app.Panel);
            app.ResolutionSpinnerLabel.Text = 'Resolution';
            app.ResolutionSpinner = uispinner(app.Panel);
            app.ResolutionSpinner.Value = 100;
            app.RangeSpinnerLabel = uilabel(app.Panel);
            app.RangeSpinnerLabel.Text = 'Range';
            app.RangeSpinner = uispinner(app.Panel);
            app.RangeSpinner.Value = -2;
            app.RangeSpinner_2 = uispinner(app.Panel);
            app.RangeSpinner_2.Value = 2;
            app.Gauge = uigauge(app.Panel, 'linear');
            app.Gauge.Enable = 'off';
            app.BeamDropDownLabel = uilabel(app.Panel);
            app.BeamDropDownLabel.Text = 'Beam';
            app.BeamDropDown = uidropdown(app.Panel);
            app.BeamDropDown.Items = {};
            app.BeamDropDown.Editable = 'on';
            app.BeamDropDown.Value = {};
            app.TmatrixDropDownLabel = uilabel(app.Panel);
            app.TmatrixDropDownLabel.Text = 'T-matrix';
            app.TmatrixDropDown = uidropdown(app.Panel);
            app.TmatrixDropDown.Items = {};
            app.TmatrixDropDown.Editable = 'on';
            app.TmatrixDropDown.Value = {};
            app.InitialPositionEditFieldLabel = uilabel(app.Panel);
            app.InitialPositionEditFieldLabel.Text = 'Initial Position';
            app.InitialPositionEditField = uieditfield(app.Panel, 'text');
            app.InitialPositionEditField.Value = '[0;0;0]';
            app.InitialRotationEditFieldLabel = uilabel(app.Panel);
            app.InitialRotationEditFieldLabel.Text = 'Initial Rotation';
            app.InitialRotationEditField = uieditfield(app.Panel, 'text');
            app.InitialRotationEditField.Value = 'eye(3)';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ForceProfile

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CalculateForceTorqueProfilesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CalculateForceTorqueProfilesUIFigure)
        end
    end
end