classdef Scatter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ScatterBeamUIFigure          matlab.ui.Figure
        Panel                        matlab.ui.container.Panel
        GenerateButton               matlab.ui.control.Button
        IncidentBeamDropDownLabel    matlab.ui.control.Label
        IncidentBeamDropDown         matlab.ui.control.DropDown
        TmatrixDropDownLabel         matlab.ui.control.Label
        TmatrixDropDown              matlab.ui.control.DropDown
        ScatteredBeamEditFieldLabel  matlab.ui.control.Label
        ScatteredBeamEditField       matlab.ui.control.EditField
        PositionEditFieldLabel       matlab.ui.control.Label
        PositionEditField            matlab.ui.control.EditField
        RotationEditFieldLabel       matlab.ui.control.Label
        RotationEditField            matlab.ui.control.EditField
        TextArea                     matlab.ui.control.TextArea
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Look for beams in workspace
            varnames = evalin('base', 'who');
            beams = {};
            particles = {};
            for ii = 1:length(varnames)
                if isa(evalin('base', varnames{ii}), 'ott.Bsc')
                    beams{end+1} = varnames{ii};
                end
                if isa(evalin('base', varnames{ii}), 'ott.Tmatrix')
                    particles{end+1} = varnames{ii};
                end
            end
            
            % Populate beam selector drop downs
            app.IncidentBeamDropDown.Items = beams;
            app.IncidentBeamDropDown.ItemsData = beams;
            app.IncidentBeamDropDown.Value = '';
            
            % Populate T-matrix selector drop down
            app.TmatrixDropDown.Items = particles;
            app.TmatrixDropDown.ItemsData = particles;
            app.TmatrixDropDown.Value = '';
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            
            % Get beam
            if isempty(app.IncidentBeamDropDown.Value)
                error('Must supply incident beam');
            end
            beam = evalin('base', app.IncidentBeamDropDown.Value);
            
            % Get T-matrix
            if isempty(app.TmatrixDropDown.Value)
                error('Must supply T-matrix');
            end
            Tmatrix = evalin('base', app.TmatrixDropDown.Value);
            
            % Check we have an output variable name
            if ~isvarname(app.ScatteredBeamEditField.Value)
                error('Must supply valid variable name for scattered beam');
            end
            
            % Get position and rotation
            position = evalin('base', app.PositionEditField.Value);
            rotation = evalin('base', app.RotationEditField.Value);
            
            % Convert rotation to matrix
            if numel(rotation) == 2
                rotation = ott.utils.rotz(rotation(2))*ott.utils.roty(rotation(1));
            else
                assert(all(size(rotation) == [3, 3]), 'Rotation must be 3x3 matrix');
            end
            
            assert(numel(position) == 3, 'Position must be a 3 element vector');
            
            % Scatter the beam
            sbeam = beam.scatter(Tmatrix, 'position', position(:), 'rotation', rotation);
            
            % Store the result
            assignin('base', app.ScatteredBeamEditField.Value, sbeam);
            
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ScatterBeamUIFigure = uifigure;
            app.ScatterBeamUIFigure.Name = 'Scatter Beam';
            app.Panel = uipanel(app.ScatterBeamUIFigure);
            app.GenerateButton = uibutton(app.Panel, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.IncidentBeamDropDownLabel = uilabel(app.Panel);
            app.IncidentBeamDropDownLabel.Text = 'Incident Beam';
            app.IncidentBeamDropDown = uidropdown(app.Panel);
            app.IncidentBeamDropDown.Items = {};
            app.IncidentBeamDropDown.Editable = 'on';
            app.IncidentBeamDropDown.Value = {};
            app.TmatrixDropDownLabel = uilabel(app.Panel);
            app.TmatrixDropDownLabel.Text = 'T-matrix';
            app.TmatrixDropDown = uidropdown(app.Panel);
            app.TmatrixDropDown.Items = {};
            app.TmatrixDropDown.Editable = 'on';
            app.TmatrixDropDown.Value = {};
            app.ScatteredBeamEditFieldLabel = uilabel(app.Panel);
            app.ScatteredBeamEditFieldLabel.Text = 'Scattered Beam';
            app.ScatteredBeamEditField = uieditfield(app.Panel, 'text');
            app.ScatteredBeamEditField.Value = 'ScatteredBeam';
            app.PositionEditFieldLabel = uilabel(app.Panel);
            app.PositionEditFieldLabel.Text = 'Position';
            app.PositionEditField = uieditfield(app.Panel, 'text');
            app.PositionEditField.Value = '[0, 0, 0]';
            app.RotationEditFieldLabel = uilabel(app.Panel);
            app.RotationEditFieldLabel.Text = 'Rotation';
            app.RotationEditField = uieditfield(app.Panel, 'text');
            app.RotationEditField.Value = 'eye(3)';
            app.TextArea = uitextarea(app.ScatterBeamUIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Calculate how an incident beam scattered from a T-matrix.  Create a new scattered beam BSC object.'; ''; 'Position specifies the x, y, z, position of the T-matrix in the beam.'; ''; 'Rotation must be either a 3x3 rotation matrix or a 2 element vector with the rotation of the T-matrix from the +z axis and the rotation from the +x towards the +y axis. '};
        end
    end

    methods (Access = public)

        % Construct app
        function app = Scatter

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ScatterBeamUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ScatterBeamUIFigure)
        end
    end
end