classdef SimpleDynamics < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SimpleDynamicsSimulationUIFigure  matlab.ui.Figure
        Panel                          matlab.ui.container.Panel
        BeamDropDownLabel              matlab.ui.control.Label
        BeamDropDown                   matlab.ui.control.DropDown
        TmatrixDropDownLabel           matlab.ui.control.Label
        TmatrixDropDown                matlab.ui.control.DropDown
        ShapeDropDownLabel             matlab.ui.control.Label
        ShapeDropDown                  matlab.ui.control.DropDown
        SimulateButton                 matlab.ui.control.Button
        Gauge                          matlab.ui.control.LinearGauge
        OutputVariableEditFieldLabel   matlab.ui.control.Label
        OutputVariableEditField        matlab.ui.control.EditField
        TabGroup                       matlab.ui.container.TabGroup
        FixedStepTab                   matlab.ui.container.Tab
        dxSpinnerLabel                 matlab.ui.control.Label
        dxSpinner                      matlab.ui.control.Spinner
        dthetaSpinnerLabel             matlab.ui.control.Label
        dthetaSpinner                  matlab.ui.control.Spinner
        FixedRateTab                   matlab.ui.container.Tab
        TimestepSpinnerLabel           matlab.ui.control.Label
        TimestepSpinner                matlab.ui.control.Spinner
        DragEditField_2Label           matlab.ui.control.Label
        DragEditField_2                matlab.ui.control.EditField
        MaxStepTab                     matlab.ui.container.Tab
        MaxdxSpinnerLabel              matlab.ui.control.Label
        MaxdxSpinner                   matlab.ui.control.Spinner
        MaxdthetaSpinnerLabel          matlab.ui.control.Label
        MaxdthetaSpinner               matlab.ui.control.Spinner
        MaxtimeSpinnerLabel            matlab.ui.control.Label
        MaxtimeSpinner                 matlab.ui.control.Spinner
        DragEditFieldLabel             matlab.ui.control.Label
        DragEditField                  matlab.ui.control.EditField
        NumStepsSpinnerLabel           matlab.ui.control.Label
        NumStepsSpinner                matlab.ui.control.Spinner
        InitialPositionEditFieldLabel  matlab.ui.control.Label
        InitialPositionEditField       matlab.ui.control.EditField
        InitialRotationEditFieldLabel  matlab.ui.control.Label
        InitialRotationEditField       matlab.ui.control.EditField
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        DropDown                       matlab.ui.control.DropDown
    end


    properties (Access = public)
        SimulationData % Data generated during a simulation
    end
    
    properties (Access = private)
        Beam % Simulation beam
        Tmatrix % Simulation T-matrix
        Shape % Current shape being displayed
    end

    methods (Access = private)
    
        function UpdateAnimation(app, x, R)
            % Update the display that shows the animation
            
            % TODO: Draw the shape in the axes at the specified position
        end
    
        function UpdateGraph(app, maxIdx)
            % Update the graph up until maxIdx
            
            % Check there is work to do
            if isempty(app.SimulationData)
                return;
            end
            
            % Handle default value for maxIdx
            if nargin == 1
                maxIdx = numel(app.SimulationData.time);
            end
            
            % TODO
            
            % TODO: Change what is displayed in the graph
            switch app.DropDown.Value
                case 'Position'
                    plot(app.UIAxes2, app.SimulationData.time(1:maxIdx), app.SimulationData.position(:, 1:maxIdx));
                    xlabel(app.UIAxes2, ['Simulation step [' app.SimulationData.time_units ']']);
                    ylabel(app.UIAxes2, 'Position [m]');
                    legend(app.UIAxes2, {'X', 'Y', 'Z'});
                case 'Rotation'
                case 'Force'
                case 'Torque'
                otherwise
                    error('OTT: Internal error: Invalid DropDown value');
            end
        end
        
        function x = GetInitialPosition(app)
            % Get the value of the initial position edit field
            x = evalin('base', app.InitialPositionEditField.Value);
            assert(isnumeric(x) && numel(x) == 3, ...
                'Initial position must be numeric 3 element vector');
            x = x(:);
        end
        
        function R = GetInitialRotation(app)
            % Get the value of the initial rotation edit field
            R = evalin('base', app.InitialRotationEditField.Value);
            
            assert(isnumeric(R) && (numel(R) == 2 || all(size(R) == [3,3])), ...
                'Rotation must be 2 element vector or 3x3 matrix');
            if numel(R) == 2
                % Generate rotation matrix
                R = ott.utils.rotz(R(2))*ott.utils.roty(R(1));
            end
        end
    
        function [ff, tt] = ForceTorque(app, x, R)
           
            [ff, tt] = ott.forcetorque(app.Beam, app.Tmatrix, 'position', x, 'rotation', R);
            ff = ff ./ app.Beam.speed;
            tt = tt ./ app.Beam.omega;
            
        end
    
        function [dt, x, R] = SimulationFixedStep(app, x, R)
            % Simulation where rotation/position step is fixed magnitude
            
            % Get properties from GUI
            dx_step = app.dxSpinner.Value;
            dtheta_step = app.dthetaSpinner.Value;
            
            dt = 1;
            
            % Calculate the force/torque on the particle
            [ff, tt] = app.ForceTorque(x, R);
            
            ff = ff ./ vecnorm(ff);
            tt = tt ./ vecnorm(tt);
            
            dx = dx_step * ff;
            dtheta = dtheta_step * tt;
            
            % Calculate new position
            if ~isnan(dx)
                x = x + dx;
            end
            
            % Calculate new rotation
            if ~isnan(dtheta)
                R =  ott.utils.rotation_matrix(dtheta) * R;
            end
        end
    
        function [dt, x, R] = SimulationFixedRate(app, x, R)
            % Simulation where time step is fixed
            % TODO
        end
    
        function [dt, x, R] = SimulationMaxStep(app, x, R)
            % Simulation where time-step is limited by maximum step size
            % TODO
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SimulationData = [];
            
            % Look for beams in workspace
            varnames = evalin('base', 'who');
            beams = {};
            particles = {};
            shapes = {};
            for ii = 1:length(varnames)
                if isa(evalin('base', varnames{ii}), 'ott.Bsc')
                    beams{end+1} = varnames{ii};
                end
                if isa(evalin('base', varnames{ii}), 'ott.Tmatrix')
                    particles{end+1} = varnames{ii};
                end
                if isa(evalin('base', varnames{ii}), 'ott.shapes.Shape')
                    shapes{end+1} = varnames{ii};
                end
            end
            
            % Populate beam selector drop downs
            app.BeamDropDown.Items = beams;
            app.BeamDropDown.ItemsData = beams;
            app.BeamDropDown.Value = '';
            
            % Populate T-matrix selector drop down
            app.TmatrixDropDown.Items = particles;
            app.TmatrixDropDown.ItemsData = particles;
            app.TmatrixDropDown.Value = '';
            
            % Populate T-matrix selector drop down
            app.ShapeDropDown.Items = {'Shape: Axes', 'Shape: Cube', 'Shape: Sphere', shapes{:}};
            app.ShapeDropDown.ItemsData = {'Shape: Axes', 'Shape: Cube', 'Shape: Sphere', shapes{:}};
            app.ShapeDropDown.Value = 'Shape: Axes';
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            app.UpdateGraph();
        end

        % Value changed function: OutputVariableEditField
        function OutputVariableEditFieldValueChanged(app, event)
            % Output data if simulation already run
            if ~isempty(app.SimulationData) && isvarname(app.OutputVariableEditField.Value)
                assignin('base', app.OutputVariableEditField.Value, app.SimulationData);
            end
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            
            % Get simulation type
            simulation_type = app.TabGroup.SelectedTab.Title;
            
            % Get number of simulation steps
            numsteps = app.NumStepsSpinner.Value;
            
            % Get beam and T-matrix
            if isempty(app.BeamDropDown.Value)
                error('Must supply a beam object');
            end
            app.Beam = evalin('base', app.BeamDropDown.Value);
            if isempty(app.TmatrixDropDown.Value)
                error('Must supply a T-matrix object');
            end
            app.Tmatrix = evalin('base', app.TmatrixDropDown.Value);
            
            % Allocate memory for output
            app.SimulationData = struct();
            app.SimulationData.position = zeros(3, numsteps);
            app.SimulationData.rotation = zeros(3, 3*numsteps);
            app.SimulationData.time = zeros(1, numsteps);
            
            % Assign simulation type and time units
            app.SimulationData.simulation_type = simulation_type;
            switch simulation_type
                case 'Fixed Step'
                    app.SimulationData.time_units = 'step';
                case 'Fixed Rate'
                    app.SimulationData.time_units = 's';
                case 'Max Step'
                    app.SimulationData.time_units = 's';
                otherwise
                    error('OTT: Internal error: Unknown tab title');
            end
            
            % Get the initial position/rotation
            x = app.GetInitialPosition();
            R = app.GetInitialRotation();
            app.SimulationData.position(:, 1) = x;
            app.SimulationData.rotation(:, 1:3) = R;
            
            % Run the simulation
            for ii = 2:numsteps
                
                % Update progress bar
                app.Gauge.Value = 100*(ii-1)/numsteps;
                
                % Calculate new position/rotation/time
                switch simulation_type
                    case 'Fixed Step'
                        [dt, x, R] = app.SimulationFixedStep(x, R);
                    case 'Fixed Rate'
                        [dt, x, R] = app.SimulationFixedRate(x, R);
                    case 'Max Step'
                        [dt, x, R] = app.SimulationMaxStep(x, R);
                    otherwise
                        error('OTT: Internal error: Unknown tab title');
                end
                
                % Store result
                app.SimulationData.time(ii) = app.SimulationData.time(ii-1) + dt;
                app.SimulationData.position(:, ii) = x;
                app.SimulationData.rotation(:, (1:3) + (ii-1)*3) = R;
                
                % Update visualisations
                app.UpdateAnimation(x, R);
                app.UpdateGraph(ii);
                drawnow;
            end
            
            % Update progress bar
            app.Gauge.Value = 100;
            
            % Store result in workspace
            if isvarname(app.OutputVariableEditField.Value)
                assignin('base', app.OutputVariableEditField.Value, app.SimulationData);
            end
        end

        % Value changed function: InitialPositionEditField
        function InitialPositionEditFieldValueChanged(app, event)

            % Update the animation
            x = app.GetInitialPosition();
            R = app.GetInitialRotation();
            app.UpdateAnimation(x, R);
        end

        % Value changed function: InitialRotationEditField
        function InitialRotationEditFieldValueChanged(app, event)

            % Update the animation
            x = app.GetInitialPosition();
            R = app.GetInitialRotation();
            app.UpdateAnimation(x, R);
        end

        % Value changed function: ShapeDropDown
        function ShapeDropDownValueChanged(app, event)

            % Get the position/rotation to draw
            if isempty(app.SimulationData)
                x = app.GetInitialPosition();
                R = app.GetInitialRotation();
            else
                x = app.SimulationData.position(:, end);
                R = app.SimulationData.rotation(:, end-2:end);
            end
            
            % TODO: Change the internal shape object
            
            % Update the animation
            app.UpdateAnimation(x, R);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SimpleDynamicsSimulationUIFigure = uifigure;
            app.SimpleDynamicsSimulationUIFigure.Name = 'Simple Dynamics Simulation';
            app.Panel = uipanel(app.SimpleDynamicsSimulationUIFigure);
            app.BeamDropDownLabel = uilabel(app.Panel);
            app.BeamDropDownLabel.Text = 'Beam';
            app.BeamDropDown = uidropdown(app.Panel);
            app.BeamDropDown.Items = {};
            app.BeamDropDown.Editable = 'on';
            app.BeamDropDown.Value = {};
            app.TmatrixDropDownLabel = uilabel(app.Panel);
            app.TmatrixDropDownLabel.Text = 'T-matrix';
            app.TmatrixDropDown = uidropdown(app.Panel);
            app.TmatrixDropDown.Items = {};
            app.TmatrixDropDown.Editable = 'on';
            app.TmatrixDropDown.Value = {};
            app.ShapeDropDownLabel = uilabel(app.Panel);
            app.ShapeDropDownLabel.Text = 'Shape';
            app.ShapeDropDown = uidropdown(app.Panel);
            app.ShapeDropDown.Items = {'Shape: Axes', 'Shape: Cube', 'Shape: Sphere'};
            app.ShapeDropDown.ItemsData = {'Shape: Axes', 'Shape: Cube', 'Shape: Sphere'};
            app.ShapeDropDown.Editable = 'on';
            app.ShapeDropDown.ValueChangedFcn = createCallbackFcn(app, @ShapeDropDownValueChanged, true);
            app.ShapeDropDown.Value = 'Shape: Axes';
            app.SimulateButton = uibutton(app.Panel, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.Gauge = uigauge(app.Panel, 'linear');
            app.Gauge.Enable = 'off';
            app.OutputVariableEditFieldLabel = uilabel(app.Panel);
            app.OutputVariableEditFieldLabel.Text = 'Output Variable';
            app.OutputVariableEditField = uieditfield(app.Panel, 'text');
            app.OutputVariableEditField.ValueChangedFcn = createCallbackFcn(app, @OutputVariableEditFieldValueChanged, true);
            app.OutputVariableEditField.Value = 'SimulationData';
            app.TabGroup = uitabgroup(app.Panel);
            app.FixedStepTab = uitab(app.TabGroup);
            app.FixedStepTab.Title = 'Fixed Step';
            app.dxSpinnerLabel = uilabel(app.FixedStepTab);
            app.dxSpinnerLabel.Text = 'dx';
            app.dxSpinner = uispinner(app.FixedStepTab);
            app.dthetaSpinnerLabel = uilabel(app.FixedStepTab);
            app.dthetaSpinnerLabel.Text = 'dtheta';
            app.dthetaSpinner = uispinner(app.FixedStepTab);
            app.FixedRateTab = uitab(app.TabGroup);
            app.FixedRateTab.Title = 'Fixed Rate';
            app.TimestepSpinnerLabel = uilabel(app.FixedRateTab);
            app.TimestepSpinnerLabel.Text = 'Time step';
            app.TimestepSpinner = uispinner(app.FixedRateTab);
            app.TimestepSpinner.Step = 1e-05;
            app.TimestepSpinner.LowerLimitInclusive = 'off';
            app.TimestepSpinner.Limits = [0 Inf];
            app.TimestepSpinner.Value = 0.001;
            app.DragEditField_2Label = uilabel(app.FixedRateTab);
            app.DragEditField_2Label.Text = 'Drag';
            app.DragEditField_2 = uieditfield(app.FixedRateTab, 'text');
            app.DragEditField_2.Value = '{eye(3), eye(3)}';
            app.MaxStepTab = uitab(app.TabGroup);
            app.MaxStepTab.Title = 'Max Step';
            app.MaxdxSpinnerLabel = uilabel(app.MaxStepTab);
            app.MaxdxSpinnerLabel.Text = 'Max dx';
            app.MaxdxSpinner = uispinner(app.MaxStepTab);
            app.MaxdthetaSpinnerLabel = uilabel(app.MaxStepTab);
            app.MaxdthetaSpinnerLabel.Text = 'Max dtheta';
            app.MaxdthetaSpinner = uispinner(app.MaxStepTab);
            app.MaxtimeSpinnerLabel = uilabel(app.MaxStepTab);
            app.MaxtimeSpinnerLabel.Text = 'Max time';
            app.MaxtimeSpinner = uispinner(app.MaxStepTab);
            app.MaxtimeSpinner.Value = 1;
            app.DragEditFieldLabel = uilabel(app.MaxStepTab);
            app.DragEditFieldLabel.Text = 'Drag';
            app.DragEditField = uieditfield(app.MaxStepTab, 'text');
            app.DragEditField.Value = '{eye(3), eye(3)}';
            app.NumStepsSpinnerLabel = uilabel(app.Panel);
            app.NumStepsSpinnerLabel.Text = 'Num. Steps';
            app.NumStepsSpinner = uispinner(app.Panel);
            app.NumStepsSpinner.Value = 1000;
            app.InitialPositionEditFieldLabel = uilabel(app.Panel);
            app.InitialPositionEditFieldLabel.Text = 'Initial Position';
            app.InitialPositionEditField = uieditfield(app.Panel, 'text');
            app.InitialPositionEditField.ValueChangedFcn = createCallbackFcn(app, @InitialPositionEditFieldValueChanged, true);
            app.InitialPositionEditField.Value = '[0, 0, 0]';
            app.InitialRotationEditFieldLabel = uilabel(app.Panel);
            app.InitialRotationEditFieldLabel.Text = 'Initial Rotation';
            app.InitialRotationEditField = uieditfield(app.Panel, 'text');
            app.InitialRotationEditField.ValueChangedFcn = createCallbackFcn(app, @InitialRotationEditFieldValueChanged, true);
            app.InitialRotationEditField.Value = 'eye(3)';
            app.UIAxes = uiaxes(app.SimpleDynamicsSimulationUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.SimpleDynamicsSimulationUIFigure);
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.DropDown = uidropdown(app.SimpleDynamicsSimulationUIFigure);
            app.DropDown.Items = {'Position', 'Rotation', 'Force', 'Torque'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Position';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SimpleDynamics

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SimpleDynamicsSimulationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SimpleDynamicsSimulationUIFigure)
        end
    end
end